classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SolutionofExactandNonExactDEUIFigure  matlab.ui.Figure
        example3xyx2Label             matlab.ui.control.Label
        examplexy2y2Label             matlab.ui.control.Label
        extraLabel                    matlab.ui.control.Label
        IFLabel                       matlab.ui.control.Label
        answerLabel                   matlab.ui.control.Label
        EnterNxyLabel                 matlab.ui.control.Label
        EnterMxyLabel                 matlab.ui.control.Label
        ReduceequationtoMdxNdy0Label  matlab.ui.control.Label
        NTextArea                     matlab.ui.control.TextArea
        NTextAreaLabel                matlab.ui.control.Label
        MTextArea                     matlab.ui.control.TextArea
        MTextAreaLabel                matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            syms x y c t

            M = str2sym(app.MTextArea.Value);
            N = str2sym(app.NTextArea.Value);
            dM=diff(M,y);
            dN=diff(N,x);

            if dM==dN %equation is exact
                %display extra info
                app.extraLabel.Interpreter = 'latex';
                app.extraLabel.Text = ['Equation is Exact'];


            else %equation is Non-exact

                %display extra info
                app.extraLabel.Interpreter = 'latex';
                app.extraLabel.Text = ['Equation is Non-Exact'];
                
                %start checking which rule fits


                % check RULE 1------homogeneous
                M1 = simplify(subs(M,[x y],[t*x t*y])/M);
                N1 = simplify(subs(N,[x y],[t*x t*y])/N);
                

                if simplify(M1 - N1) == 0 
                    disp('The equation is homogeneous');

                    if simplify(x*M + y*N) ~= 0
                        IF = simplify(1/(x*M + y*N));
                        disp('Integrating Factor (Rule 1):')
                        %display IF
                        app.IFLabel.Interpreter = 'latex';
                        raw_latex=latex(sym(IF));
                        app.IFLabel.Text = ['Integrating Factor: $$' raw_latex '$$'];
                        disp(IF)
                    else
                        disp('Rule 1 not applicable');
                    end
                
                % check RULE 2 & 3 & 4
                else
                    simplify(dM - dN)
                    Fx = simplify((dM - dN)/N);
                    Fy = simplify((dN - dM)/M);
                    
                    %check rule 3
                    if ~has(Fx,y) 
                        IF = exp(int(Fx,x));
                        disp('Integrating Factor (Rule 3):')
                        %display IF
                        app.IFLabel.Interpreter = 'latex';
                        raw_latex=latex(sym(IF));
                        app.IFLabel.Text = ['Integrating Factor: $$' raw_latex '$$'];
                        disp(IF)
                    
                    %check rule 4
                    elseif ~has(Fy,x) 
                        IF = exp(int(Fy,y));
                        disp('Integrating Factor (Rule 4):')
                        %display IF
                        app.IFLabel.Interpreter = 'latex';
                        raw_latex=latex(sym(IF));
                        app.IFLabel.Text = ['$$' raw_latex '$$'];
                        disp(IF)
            
                    % check rule 2
                    elseif simplify(x*M - y*N) ~= 0
                        IF = simplify(1/(x*M - y*N));
                        disp('Integrating Factor (Rule 2):')
                        %display IF
                        app.IFLabel.Interpreter = 'latex';
                        raw_latex=latex(sym(IF));
                        app.IFLabel.Text = ['Integrating Factor: $$' raw_latex '$$'];
                        
            
                    else
                        app.IFLabel.Text = ['Integrating Factor cannot be determined'];
                        return;
                    end
                end


                M=M*IF;
                N=N*IF;


            end


            M_ans=int(M,x);

            A=diff(M_ans, y);
            N_without_x=N-A;

            N_ans=int(N_without_x, y);


            General_solution=M_ans+N_ans==c;
            % app.ansTextArea.Value = char(General_solution);

            app.answerLabel.Interpreter = 'latex';
            raw_latex=latex(sym(General_solution));
            app.answerLabel.FontColor = [0 0.2 0.6];
            app.answerLabel.Text = ['General Solution is: $$' raw_latex '$$'];


            % x*y-2*y^2
            % 3*x*y-x^2


            % 
            % %app.ansTextArea.Value = char(General_solution);
            % app.ansTextArea.Value = latex(sym(General_solution));
            % 
            % % 1. Convert symbolic to LaTeX string
            % raw_latex = latex(General_solution);
            % 
            % % 2. Build the "Textbook" HTML string
            % % This template includes the MathJax engine and centering
            % html_template = [...
            %     '<html>' ...
            %     '<head>' ...
            %     '<script src="https://polyfill.io/v3/polyfill.min.js?features=es6"></script>' ...
            %     '<script id="MathJax-script" src="https://cdn.jsdelivr.net/npm/mathjax@3/es5/tex-mml-chtml.js"></script>' ...
            %     '</head>' ...
            %     '<body style="background-color:transparent; display:flex; justify-content:center; align-items:center; height:100vh; margin:0;">' ...
            %     '<div style="font-size:24px;">' ...
            %     '\\ ' raw_latex ' \\' ... % '\[ \]' triggers high-quality display math
            %     '</div>' ...
            %     '</body>' ...
            %     '</html>'];
            % 
            % % 3. Assign to your HTML component
            % app.HTML.HTMLSource = html_template;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SolutionofExactandNonExactDEUIFigure = uifigure('Visible', 'off');
            app.SolutionofExactandNonExactDEUIFigure.Name = 'Solution of Exact and Non-Exact DE';
            app.CalculateButton = uibutton(app.SolutionofExactandNonExactDEUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MTextAreaLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.MTextAreaLabel.Text = 'M';
            app.MTextArea = uitextarea(app.SolutionofExactandNonExactDEUIFigure);
            app.NTextAreaLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.NTextAreaLabel.Text = 'N';
            app.NTextArea = uitextarea(app.SolutionofExactandNonExactDEUIFigure);
            app.ReduceequationtoMdxNdy0Label = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.ReduceequationtoMdxNdy0Label.Text = 'Reduce equation to Mdx+Ndy=0';
            app.EnterMxyLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.EnterMxyLabel.Text = 'Enter M(x,y)';
            app.EnterNxyLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.EnterNxyLabel.Text = 'Enter N(x,y)';
            app.answerLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.answerLabel.Text = 'answer';
            app.IFLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.IFLabel.Text = 'IF';
            app.extraLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.extraLabel.Text = 'extra';
            app.examplexy2y2Label = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.examplexy2y2Label.Text = 'example: (x*y-2*y^2)';
            app.example3xyx2Label = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.example3xyx2Label.Text = 'example: (3*x*y-x^2)';
            % Show the figure after all components are created
            app.SolutionofExactandNonExactDEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SolutionofExactandNonExactDEUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SolutionofExactandNonExactDEUIFigure)
        end
    end
end